classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplayPanel                    matlab.ui.container.Panel
        EstruntimeminsTextArea          matlab.ui.control.TextArea
        EstruntimeminsTextAreaLabel     matlab.ui.control.Label
        DropdeadatTextArea              matlab.ui.control.TextArea
        DropdeadatTextAreaLabel         matlab.ui.control.Label
        BasedontheabovesettingsLabel    matlab.ui.control.Label
        TotalIterationsTextArea         matlab.ui.control.TextArea
        TotalIterationsTextAreaLabel    matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        InitalAirTempsimEditField       matlab.ui.control.NumericEditField
        InitalAirTempsimEditFieldLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        IncludeArduinoHardwareCheckBox  matlab.ui.control.CheckBox
        IncludeNIhardwareCheckBox       matlab.ui.control.CheckBox
        SimulationonlyCheckBox          matlab.ui.control.CheckBox
        StepsEditField                  matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        AlltemperaturesinCelsiusLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TimeStepsecEditField            matlab.ui.control.NumericEditField
        TimeStepsecEditFieldLabel       matlab.ui.control.Label
        InitialHeaterTempEditField      matlab.ui.control.NumericEditField
        InitialHeaterTempEditFieldLabel  matlab.ui.control.Label
        AmbientTempEditField            matlab.ui.control.NumericEditField
        AmbientTempEditFieldLabel       matlab.ui.control.Label
        RelativeHumidityEditField       matlab.ui.control.NumericEditField
        RelativeHumidityLabel           matlab.ui.control.Label
        SetpointsLabel                  matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        settings {isstruct}
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setpoints = struct('T', 0, 'phi', 0);
            app.settings = struct(...
                'setpoints', setpoints,...
                'T_g0_sim', 0,...
                'T_o', 0,...
                'T_h0', 0,...
                'T_s', 0,...
                'N', 0,... % 30=1min irl
                'off', 1e6,...
                'simulation', false,...
                'ni', false,...
                'arduino', false);
            app.InitalAirTempsimEditField.Enable = false;
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if value == true
                app.RunButton.Enable = false;
                app.settings.setpoints.T = app.TemperatureEditField.Value;
                app.settings.setpoints.phi = app.RelativeHumidityEditField.Value;
                app.settings.T_g0_sim = app.InitalAirTempsimEditField.Value;
                app.settings.T_o = app.AmbientTempEditField.Value;
                app.settings.T_h0 = app.InitialHeaterTempEditField.Value;
                app.settings.T_s = app.TimeStepsecEditField.Value;
                app.settings.N = app.StepsEditField.Value;
                app.settings.simulation = app.SimulationonlyCheckBox.Value;
                app.settings.ni = app.IncludeNIhardwareCheckBox.Value;
                app.settings.arduino = app.IncludeArduinoHardwareCheckBox.Value;
                app.settings.max_iterations = app.settings.N/app.settings.T_s;
                app.settings.stop_at = app.settings.off/app.settings.T_s;
                app.TotalIterationsTextArea.Value = num2str(app.settings.max_iterations);
                app.DropdeadatTextArea.Value = num2str(app.settings.stop_at);
                app.EstruntimeminsTextArea.Value = num2str(app.settings.N/30);
                pause(5)
            end
            app.RunButton.Value = false;
            app.RunButton.Enable = true;
        end

        % Value changed function: SimulationonlyCheckBox
        function SimulationonlyCheckBoxValueChanged(app, event)
            value = app.SimulationonlyCheckBox.Value;
            if value == true
                app.InitalAirTempsimEditField.Enable = true;
            else 
                app.InitalAirTempsimEditField.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.TemperatureEditFieldLabel = uilabel(app.SettingsPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SetpointsLabel = uilabel(app.SettingsPanel);
            app.SetpointsLabel.Text = 'Setpoints';
            app.RelativeHumidityLabel = uilabel(app.SettingsPanel);
            app.RelativeHumidityLabel.Text = 'Relative Humidity (%)';
            app.RelativeHumidityEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.AmbientTempEditFieldLabel = uilabel(app.SettingsPanel);
            app.AmbientTempEditFieldLabel.Text = 'Ambient Temp.';
            app.AmbientTempEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.InitialHeaterTempEditFieldLabel = uilabel(app.SettingsPanel);
            app.InitialHeaterTempEditFieldLabel.Text = 'Initial Heater Temp.';
            app.InitialHeaterTempEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.TimeStepsecEditFieldLabel = uilabel(app.SettingsPanel);
            app.TimeStepsecEditFieldLabel.Text = 'Time Step (sec)';
            app.TimeStepsecEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '.........................................................';
            app.AlltemperaturesinCelsiusLabel = uilabel(app.SettingsPanel);
            app.AlltemperaturesinCelsiusLabel.Text = 'All temperatures in Celsius.';
            app.StepsEditFieldLabel = uilabel(app.SettingsPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SimulationonlyCheckBox = uicheckbox(app.SettingsPanel);
            app.SimulationonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulationonlyCheckBoxValueChanged, true);
            app.SimulationonlyCheckBox.Text = 'Simulation only';
            app.IncludeNIhardwareCheckBox = uicheckbox(app.SettingsPanel);
            app.IncludeNIhardwareCheckBox.Text = 'Include NI hardware';
            app.IncludeArduinoHardwareCheckBox = uicheckbox(app.SettingsPanel);
            app.IncludeArduinoHardwareCheckBox.Text = 'Include Arduino Hardware';
            app.RunButton = uibutton(app.SettingsPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.InitalAirTempsimEditFieldLabel = uilabel(app.SettingsPanel);
            app.InitalAirTempsimEditFieldLabel.Text = 'Inital Air Temp. (sim)';
            app.InitalAirTempsimEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.TotalIterationsTextAreaLabel = uilabel(app.DisplayPanel);
            app.TotalIterationsTextAreaLabel.Text = 'Total Iterations';
            app.TotalIterationsTextArea = uitextarea(app.DisplayPanel);
            app.BasedontheabovesettingsLabel = uilabel(app.DisplayPanel);
            app.BasedontheabovesettingsLabel.Text = 'Based on the above settings:';
            app.DropdeadatTextAreaLabel = uilabel(app.DisplayPanel);
            app.DropdeadatTextAreaLabel.Text = 'Drop dead at';
            app.DropdeadatTextArea = uitextarea(app.DisplayPanel);
            app.EstruntimeminsTextAreaLabel = uilabel(app.DisplayPanel);
            app.EstruntimeminsTextAreaLabel.Text = 'Est run time (mins)';
            app.EstruntimeminsTextArea = uitextarea(app.DisplayPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end