classdef arda_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MasstotalmeasuredkgEditField    matlab.ui.control.NumericEditField
        MasstotalmeasuredkgEditFieldLabel  matlab.ui.control.Label
        DistanceLIDARtomaterialmEditField  matlab.ui.control.NumericEditField
        DistanceLIDARtomaterialmEditFieldLabel  matlab.ui.control.Label
        RuntimeParametersPanel          matlab.ui.container.Panel
        ElapsedTimeTextArea             matlab.ui.control.TextArea
        ElapsedTimeTextAreaLabel        matlab.ui.control.Label
        max_iterationsEditField         matlab.ui.control.NumericEditField
        max_iterationsEditFieldLabel    matlab.ui.control.Label
        iEditField                      matlab.ui.control.NumericEditField
        iEditFieldLabel                 matlab.ui.control.Label
        Copyright2021ForestConceptsLLCLabel  matlab.ui.control.Label
        ARDALabel                       matlab.ui.control.Label
        SetupPanel                      matlab.ui.container.Panel
        SetpointProfilePanel            matlab.ui.container.Panel
        EditCurrentButton               matlab.ui.control.Button
        LoadExistingButton              matlab.ui.control.Button
        CreateNewButton                 matlab.ui.control.Button
        HeaterDropDown                  matlab.ui.control.DropDown
        HeaterDropDownLabel             matlab.ui.control.Label
        UsingloadcellCheckBox           matlab.ui.control.CheckBox
        UsingRHsensorCheckBox           matlab.ui.control.CheckBox
        UsingLIDARCheckBox              matlab.ui.control.CheckBox
        UsingNIHardwareCheckBox         matlab.ui.control.CheckBox
        UsingArduinoHardwareCheckBox    matlab.ui.control.CheckBox
        SimulationonlyCheckBox          matlab.ui.control.CheckBox
        ORLabel                         matlab.ui.control.Label
        ProgressBarCheckBox             matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        max_iterationsEditField_2       matlab.ui.control.NumericEditField
        max_iterationsEditField_2Label  matlab.ui.control.Label
        LivePlotCheckBox                matlab.ui.control.CheckBox
        OutputsPanel                    matlab.ui.container.Panel
        vEditField                      matlab.ui.control.NumericEditField
        vEditFieldLabel                 matlab.ui.control.Label
        m_dotEditField                  matlab.ui.control.NumericEditField
        m_dotEditFieldLabel             matlab.ui.control.Label
        V_hEditField                    matlab.ui.control.NumericEditField
        V_hEditFieldLabel               matlab.ui.control.Label
        P_hEditField                    matlab.ui.control.NumericEditField
        P_hEditFieldLabel               matlab.ui.control.Label
        UIAxes_16                       matlab.ui.control.UIAxes
        UIAxes_19                       matlab.ui.control.UIAxes
        UIAxes_18                       matlab.ui.control.UIAxes
        UIAxes_17                       matlab.ui.control.UIAxes
        StatePanel                      matlab.ui.container.Panel
        EMERGENCYSTOPButton             matlab.ui.control.Button
        m_aEditField                    matlab.ui.control.NumericEditField
        m_aEditFieldLabel               matlab.ui.control.Label
        m_vEditField                    matlab.ui.control.NumericEditField
        m_vEditFieldLabel               matlab.ui.control.Label
        p_sEditField                    matlab.ui.control.NumericEditField
        p_sEditFieldLabel               matlab.ui.control.Label
        phiEditField                    matlab.ui.control.NumericEditField
        phiEditFieldLabel               matlab.ui.control.Label
        V_gEditField                    matlab.ui.control.NumericEditField
        V_gEditFieldLabel               matlab.ui.control.Label
        p_aEditField                    matlab.ui.control.NumericEditField
        p_aEditFieldLabel               matlab.ui.control.Label
        p_vEditField                    matlab.ui.control.NumericEditField
        p_vEditFieldLabel               matlab.ui.control.Label
        p_gEditField                    matlab.ui.control.NumericEditField
        p_gEditFieldLabel               matlab.ui.control.Label
        T_oEditField                    matlab.ui.control.NumericEditField
        T_oEditFieldLabel               matlab.ui.control.Label
        T_hEditField                    matlab.ui.control.NumericEditField
        T_hEditFieldLabel               matlab.ui.control.Label
        T_gEditField                    matlab.ui.control.NumericEditField
        T_gEditFieldLabel               matlab.ui.control.Label
        UIAxes_15                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_lidar                    matlab.ui.control.UIAxes
        UIAxes_profile                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        profile_creator_app % Description
    end
    
    properties (Access = public)
        time
        setpoints
    end
    
    methods (Access = public)
        
        function update_plot(app)

            cla(app.UIAxes_profile)
            app.max_iterationsEditField_2.Value = app.time(end);
            app.max_iterationsEditField_2.Enable = 'off';
            plot(app.UIAxes_profile, app.time, app.setpoints)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin("base","clear; close all; clc")
        end

        % Button pushed function: CreateNewButton
        function CreateNewButtonPushed(app, event)
            app.profile_creator_app = profile_creator(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = "off";
            app.CreateNewButton.Enable = "off";
            app.LoadExistingButton.Enable = "off";
            app.EditCurrentButton.Enable = "off";
            load("controllers.mat","controllers")
            load('C.mat','C')
            for i = 1:length(controllers)
                controllers{i} = C;
            end
            
            bob = arda();
            bob.controllers = controllers;
            bob.simulation_only = app.SimulationonlyCheckBox.Value;
            bob.using_arduino_hardware = app.UsingArduinoHardwareCheckBox.Value;
            bob.using_ni_hardware = app.UsingNIHardwareCheckBox.Value;
            bob.using_lidar = app.UsingLIDARCheckBox.Value;
            bob.using_relative_humidity_sensor = app.UsingRHsensorCheckBox.Value;
            if strcmp(app.HeaterDropDown.Value, 'none') && bob.using_ni_hardware
                bob.heater = 'ni';
            else
                bob.heater = app.HeaterDropDown.Value;
            end
            bob.using_load_cell = app.UsingloadcellCheckBox.Value;
            
            bob.max_iterations = app.max_iterationsEditField_2.Value;
            bob.live_plot = app.LivePlotCheckBox.Value;
            
            bob.using_app = true;
            bob.t = app.time;
            bob.s = app.setpoints;
            app.UIAxes_12.XLim = [0 bob.max_iterations];
            bob = main(bob,"app",app);
            save('bob.mat','bob')
            disp('Done.')
            app.RunButton.Enable = "on";
            app.CreateNewButton.Enable = "on";
            app.LoadExistingButton.Enable = "on";
            app.EditCurrentButton.Enable = "on"; 
        end

        % Button pushed function: LoadExistingButton
        function LoadExistingButtonPushed(app, event)
            filter = {'*.mat'};
            title = 'Select existing setpoint profile';
            [file, path] = uigetfile(filter, title);
            
            if isequal(file,0)
                return
            else
                S = load(fullfile(path,file),"time","setpoints");
                if length(S.time) == length(S.setpoints)
                    app.time = S.time;
                    app.setpoints = S.setpoints;
                    app.update_plot();
                else
                    error("Profile failed to load.")
                end
            end
        end

        % Button pushed function: EditCurrentButton
        function EditCurrentButtonPushed(app, event)
            app.profile_creator_app = profile_creator(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            evalin("base", "notify(bob,'emergency')")
            app.EMERGENCYSTOPButton.Text = "STOPPING";
            app.EMERGENCYSTOPButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_profile = uiaxes(app.UIFigure);
            title(app.UIAxes_profile, 'Setpoint Profile')
            xlabel(app.UIAxes_profile, 'Steps')
            ylabel(app.UIAxes_profile, 'Value')
            zlabel(app.UIAxes_profile, 'Z')
            app.UIAxes_lidar = uiaxes(app.UIFigure);
            title(app.UIAxes_lidar, 'LIDAR')
            zlabel(app.UIAxes_lidar, 'Z')
            app.StatePanel = uipanel(app.UIFigure);
            app.StatePanel.Title = 'State';
            app.UIAxes_4 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.YLim = [0 1];
            app.UIAxes_6 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.YLim = [0 1];
            app.UIAxes_7 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.YLim = [0 105];
            app.UIAxes_8 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.YLim = [0 105];
            app.UIAxes_9 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.YLim = [95 105];
            app.UIAxes_10 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_10.YLim = [15 30];
            app.UIAxes_11 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_11.YLim = [20 250];
            app.UIAxes_12 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_12.YLim = [15 40];
            app.UIAxes_13 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_13.YLim = [0 1];
            app.UIAxes_14 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_14.YLim = [0 10];
            app.UIAxes_15 = uiaxes(app.StatePanel);
            zlabel(app.UIAxes_15, 'Z')
            app.UIAxes_15.YLim = [0 105];
            app.T_gEditFieldLabel = uilabel(app.StatePanel);
            app.T_gEditFieldLabel.Text = 'T_g';
            app.T_gEditField = uieditfield(app.StatePanel, 'numeric');
            app.T_hEditFieldLabel = uilabel(app.StatePanel);
            app.T_hEditFieldLabel.Text = 'T_h';
            app.T_hEditField = uieditfield(app.StatePanel, 'numeric');
            app.T_oEditFieldLabel = uilabel(app.StatePanel);
            app.T_oEditFieldLabel.Text = 'T_o';
            app.T_oEditField = uieditfield(app.StatePanel, 'numeric');
            app.p_gEditFieldLabel = uilabel(app.StatePanel);
            app.p_gEditFieldLabel.Text = 'p_g';
            app.p_gEditField = uieditfield(app.StatePanel, 'numeric');
            app.p_vEditFieldLabel = uilabel(app.StatePanel);
            app.p_vEditFieldLabel.Text = 'p_v';
            app.p_vEditField = uieditfield(app.StatePanel, 'numeric');
            app.p_aEditFieldLabel = uilabel(app.StatePanel);
            app.p_aEditFieldLabel.Text = 'p_a';
            app.p_aEditField = uieditfield(app.StatePanel, 'numeric');
            app.V_gEditFieldLabel = uilabel(app.StatePanel);
            app.V_gEditFieldLabel.Text = 'V_g';
            app.V_gEditField = uieditfield(app.StatePanel, 'numeric');
            app.phiEditFieldLabel = uilabel(app.StatePanel);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.StatePanel, 'numeric');
            app.p_sEditFieldLabel = uilabel(app.StatePanel);
            app.p_sEditFieldLabel.Text = 'p_s';
            app.p_sEditField = uieditfield(app.StatePanel, 'numeric');
            app.m_vEditFieldLabel = uilabel(app.StatePanel);
            app.m_vEditFieldLabel.Text = 'm_v';
            app.m_vEditField = uieditfield(app.StatePanel, 'numeric');
            app.m_aEditFieldLabel = uilabel(app.StatePanel);
            app.m_aEditFieldLabel.Text = 'm_a';
            app.m_aEditField = uieditfield(app.StatePanel, 'numeric');
            app.EMERGENCYSTOPButton = uibutton(app.StatePanel, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.UIAxes_17 = uiaxes(app.OutputsPanel);
            zlabel(app.UIAxes_17, 'Z')
            app.UIAxes_17.YLim = [0 10];
            app.UIAxes_18 = uiaxes(app.OutputsPanel);
            zlabel(app.UIAxes_18, 'Z')
            app.UIAxes_18.YLim = [0 1];
            app.UIAxes_19 = uiaxes(app.OutputsPanel);
            zlabel(app.UIAxes_19, 'Z')
            app.UIAxes_19.YLim = [0 1];
            app.UIAxes_16 = uiaxes(app.OutputsPanel);
            zlabel(app.UIAxes_16, 'Z')
            app.UIAxes_16.YLim = [0 1];
            app.P_hEditFieldLabel = uilabel(app.OutputsPanel);
            app.P_hEditFieldLabel.Text = 'P_h';
            app.P_hEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.V_hEditFieldLabel = uilabel(app.OutputsPanel);
            app.V_hEditFieldLabel.Text = 'V_h';
            app.V_hEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.m_dotEditFieldLabel = uilabel(app.OutputsPanel);
            app.m_dotEditFieldLabel.Text = 'm_dot';
            app.m_dotEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.vEditFieldLabel = uilabel(app.OutputsPanel);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Setup';
            app.LivePlotCheckBox = uicheckbox(app.SetupPanel);
            app.LivePlotCheckBox.Text = 'Live Plot';
            app.LivePlotCheckBox.Value = true;
            app.max_iterationsEditField_2Label = uilabel(app.SetupPanel);
            app.max_iterationsEditField_2Label.Text = 'max_iterations';
            app.max_iterationsEditField_2 = uieditfield(app.SetupPanel, 'numeric');
            app.max_iterationsEditField_2.Value = 300;
            app.RunButton = uibutton(app.SetupPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ProgressBarCheckBox = uicheckbox(app.SetupPanel);
            app.ProgressBarCheckBox.Text = 'Progress Bar';
            app.ORLabel = uilabel(app.SetupPanel);
            app.ORLabel.Text = 'OR';
            app.SimulationonlyCheckBox = uicheckbox(app.SetupPanel);
            app.SimulationonlyCheckBox.Text = 'Simulation only';
            app.UsingArduinoHardwareCheckBox = uicheckbox(app.SetupPanel);
            app.UsingArduinoHardwareCheckBox.Text = 'Using Arduino Hardware';
            app.UsingNIHardwareCheckBox = uicheckbox(app.SetupPanel);
            app.UsingNIHardwareCheckBox.Text = 'Using NI Hardware';
            app.UsingNIHardwareCheckBox.Value = true;
            app.UsingLIDARCheckBox = uicheckbox(app.SetupPanel);
            app.UsingLIDARCheckBox.Text = 'Using LIDAR';
            app.UsingRHsensorCheckBox = uicheckbox(app.SetupPanel);
            app.UsingRHsensorCheckBox.Text = 'Using RH sensor';
            app.UsingloadcellCheckBox = uicheckbox(app.SetupPanel);
            app.UsingloadcellCheckBox.Text = 'Using load cell';
            app.HeaterDropDownLabel = uilabel(app.SetupPanel);
            app.HeaterDropDownLabel.Text = 'Heater';
            app.HeaterDropDown = uidropdown(app.SetupPanel);
            app.HeaterDropDown.Items = {'none', 'ni', 'arduino'};
            app.HeaterDropDown.Value = 'ni';
            app.SetpointProfilePanel = uipanel(app.SetupPanel);
            app.SetpointProfilePanel.Title = 'Setpoint Profile';
            app.CreateNewButton = uibutton(app.SetpointProfilePanel, 'push');
            app.CreateNewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewButtonPushed, true);
            app.CreateNewButton.Text = 'Create New';
            app.LoadExistingButton = uibutton(app.SetpointProfilePanel, 'push');
            app.LoadExistingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingButtonPushed, true);
            app.LoadExistingButton.Text = 'Load Existing';
            app.EditCurrentButton = uibutton(app.SetpointProfilePanel, 'push');
            app.EditCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @EditCurrentButtonPushed, true);
            app.EditCurrentButton.Text = 'Edit Current';
            app.ARDALabel = uilabel(app.UIFigure);
            app.ARDALabel.Text = 'ARDA';
            app.Copyright2021ForestConceptsLLCLabel = uilabel(app.UIFigure);
            app.Copyright2021ForestConceptsLLCLabel.Text = 'Copyright 2021, Forest Concepts, LLC';
            app.RuntimeParametersPanel = uipanel(app.UIFigure);
            app.RuntimeParametersPanel.Title = 'Runtime Parameters';
            app.iEditFieldLabel = uilabel(app.RuntimeParametersPanel);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.RuntimeParametersPanel, 'numeric');
            app.max_iterationsEditFieldLabel = uilabel(app.RuntimeParametersPanel);
            app.max_iterationsEditFieldLabel.Text = 'max_iterations';
            app.max_iterationsEditField = uieditfield(app.RuntimeParametersPanel, 'numeric');
            app.ElapsedTimeTextAreaLabel = uilabel(app.RuntimeParametersPanel);
            app.ElapsedTimeTextAreaLabel.Text = 'Elapsed Time';
            app.ElapsedTimeTextArea = uitextarea(app.RuntimeParametersPanel);
            app.DistanceLIDARtomaterialmEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceLIDARtomaterialmEditFieldLabel.Text = 'Distance (LIDAR to material) (m)';
            app.DistanceLIDARtomaterialmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasstotalmeasuredkgEditFieldLabel = uilabel(app.UIFigure);
            app.MasstotalmeasuredkgEditFieldLabel.Text = 'Mass (total measured) (kg)';
            app.MasstotalmeasuredkgEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arda_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end