classdef profile_creator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        pointsEditField          matlab.ui.control.NumericEditField
        pointsLabel              matlab.ui.control.Label
        maxtempEditField         matlab.ui.control.NumericEditField
        maxtempEditFieldLabel    matlab.ui.control.Label
        maxtimeEditField         matlab.ui.control.NumericEditField
        maxtimeEditFieldLabel    matlab.ui.control.Label
        CreategraphicallyLabel   matlab.ui.control.Label
        SaveandExitButton        matlab.ui.control.Button
        BLabel                   matlab.ui.control.Label
        ALabel                   matlab.ui.control.Label
        EnterprofilecoordinatesmanuallyLabel  matlab.ui.control.Label
        ORLabel                  matlab.ui.control.Label
        y10EditField             matlab.ui.control.NumericEditField
        y10EditFieldLabel        matlab.ui.control.Label
        y9EditField              matlab.ui.control.NumericEditField
        y9EditFieldLabel         matlab.ui.control.Label
        y8EditField              matlab.ui.control.NumericEditField
        y8EditFieldLabel         matlab.ui.control.Label
        y7EditField              matlab.ui.control.NumericEditField
        y7EditFieldLabel         matlab.ui.control.Label
        y6EditField              matlab.ui.control.NumericEditField
        y6EditFieldLabel         matlab.ui.control.Label
        y5EditField              matlab.ui.control.NumericEditField
        y5EditFieldLabel         matlab.ui.control.Label
        y4EditField              matlab.ui.control.NumericEditField
        y4EditFieldLabel         matlab.ui.control.Label
        y3EditField              matlab.ui.control.NumericEditField
        y3EditFieldLabel         matlab.ui.control.Label
        y2EditField              matlab.ui.control.NumericEditField
        y2EditFieldLabel         matlab.ui.control.Label
        y1EditField              matlab.ui.control.NumericEditField
        y1EditFieldLabel         matlab.ui.control.Label
        x10EditField             matlab.ui.control.NumericEditField
        x10EditFieldLabel        matlab.ui.control.Label
        x9EditField              matlab.ui.control.NumericEditField
        x9EditFieldLabel         matlab.ui.control.Label
        x8EditField              matlab.ui.control.NumericEditField
        x8EditFieldLabel         matlab.ui.control.Label
        x7EditField              matlab.ui.control.NumericEditField
        x7EditFieldLabel         matlab.ui.control.Label
        x6EditField              matlab.ui.control.NumericEditField
        x6EditFieldLabel         matlab.ui.control.Label
        x5EditField              matlab.ui.control.NumericEditField
        x5EditFieldLabel         matlab.ui.control.Label
        x4EditField              matlab.ui.control.NumericEditField
        x4EditFieldLabel         matlab.ui.control.Label
        x3EditField              matlab.ui.control.NumericEditField
        x3EditFieldLabel         matlab.ui.control.Label
        x2EditField              matlab.ui.control.NumericEditField
        x2EditFieldLabel         matlab.ui.control.Label
        x1EditField              matlab.ui.control.NumericEditField
        x1EditFieldLabel         matlab.ui.control.Label
        CreateGraphicallyButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        the_main_app
        setpoint
        time
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.the_main_app = main_app;
            app.maxtimeEditField.Value = app.the_main_app.max_iterationsEditField_2.Value;
        end

        % Button pushed function: CreateGraphicallyButton
        function CreateGraphicallyButtonPushed(app, event)
            t_min = 0;
            t_max = app.maxtimeEditField.Value;
            T_min = 20;
            T_max = app.maxtempEditField.Value;
            n = app.pointsEditField.Value;
            dragpoints(app, linspace(t_min,t_max,n), ...
                linspace(T_min,T_max,n), t_min, t_max, T_min, T_max)
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            app.time(1) = app.x1EditField.Value;
            app.time(2) = app.x2EditField.Value;
            app.time(3) = app.x3EditField.Value;
            app.time(4) = app.x4EditField.Value;
            app.time(5) = app.x5EditField.Value;
            app.time(6) = app.x6EditField.Value;
            app.time(7) = app.x7EditField.Value;
            app.time(8) = app.x8EditField.Value;
            app.time(9) = app.x9EditField.Value;
            app.time(10) = app.x10EditField.Value;

            app.setpoint(1) = app.y1EditField.Value;
            app.setpoint(2) = app.y2EditField.Value;
            app.setpoint(3) = app.y3EditField.Value;
            app.setpoint(4) = app.y4EditField.Value;
            app.setpoint(5) = app.y5EditField.Value;
            app.setpoint(6) = app.y6EditField.Value;
            app.setpoint(7) = app.y7EditField.Value;
            app.setpoint(8) = app.y8EditField.Value;
            app.setpoint(9) = app.y9EditField.Value;
            app.setpoint(10) = app.y10EditField.Value;
           
            update_plot(app.the_main_app, app.time, app.setpoint)
            
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CreateGraphicallyButton = uibutton(app.UIFigure, 'push');
            app.CreateGraphicallyButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGraphicallyButtonPushed, true);
            app.CreateGraphicallyButton.Text = 'Create Graphically';
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x(1)';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x(2)';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x3EditFieldLabel = uilabel(app.UIFigure);
            app.x3EditFieldLabel.Text = 'x(3)';
            app.x3EditField = uieditfield(app.UIFigure, 'numeric');
            app.x4EditFieldLabel = uilabel(app.UIFigure);
            app.x4EditFieldLabel.Text = 'x(4)';
            app.x4EditField = uieditfield(app.UIFigure, 'numeric');
            app.x5EditFieldLabel = uilabel(app.UIFigure);
            app.x5EditFieldLabel.Text = 'x(5)';
            app.x5EditField = uieditfield(app.UIFigure, 'numeric');
            app.x6EditFieldLabel = uilabel(app.UIFigure);
            app.x6EditFieldLabel.Text = 'x(6)';
            app.x6EditField = uieditfield(app.UIFigure, 'numeric');
            app.x7EditFieldLabel = uilabel(app.UIFigure);
            app.x7EditFieldLabel.Text = 'x(7)';
            app.x7EditField = uieditfield(app.UIFigure, 'numeric');
            app.x8EditFieldLabel = uilabel(app.UIFigure);
            app.x8EditFieldLabel.Text = 'x(8)';
            app.x8EditField = uieditfield(app.UIFigure, 'numeric');
            app.x9EditFieldLabel = uilabel(app.UIFigure);
            app.x9EditFieldLabel.Text = 'x(9)';
            app.x9EditField = uieditfield(app.UIFigure, 'numeric');
            app.x10EditFieldLabel = uilabel(app.UIFigure);
            app.x10EditFieldLabel.Text = 'x(10)';
            app.x10EditField = uieditfield(app.UIFigure, 'numeric');
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y(1)';
            app.y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y(2)';
            app.y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.y3EditFieldLabel = uilabel(app.UIFigure);
            app.y3EditFieldLabel.Text = 'y(3)';
            app.y3EditField = uieditfield(app.UIFigure, 'numeric');
            app.y4EditFieldLabel = uilabel(app.UIFigure);
            app.y4EditFieldLabel.Text = 'y(4)';
            app.y4EditField = uieditfield(app.UIFigure, 'numeric');
            app.y5EditFieldLabel = uilabel(app.UIFigure);
            app.y5EditFieldLabel.Text = 'y(5)';
            app.y5EditField = uieditfield(app.UIFigure, 'numeric');
            app.y6EditFieldLabel = uilabel(app.UIFigure);
            app.y6EditFieldLabel.Text = 'y(6)';
            app.y6EditField = uieditfield(app.UIFigure, 'numeric');
            app.y7EditFieldLabel = uilabel(app.UIFigure);
            app.y7EditFieldLabel.Text = 'y(7)';
            app.y7EditField = uieditfield(app.UIFigure, 'numeric');
            app.y8EditFieldLabel = uilabel(app.UIFigure);
            app.y8EditFieldLabel.Text = 'y(8)';
            app.y8EditField = uieditfield(app.UIFigure, 'numeric');
            app.y9EditFieldLabel = uilabel(app.UIFigure);
            app.y9EditFieldLabel.Text = 'y(9)';
            app.y9EditField = uieditfield(app.UIFigure, 'numeric');
            app.y10EditFieldLabel = uilabel(app.UIFigure);
            app.y10EditFieldLabel.Text = 'y(10)';
            app.y10EditField = uieditfield(app.UIFigure, 'numeric');
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.EnterprofilecoordinatesmanuallyLabel = uilabel(app.UIFigure);
            app.EnterprofilecoordinatesmanuallyLabel.Text = 'Enter profile coordinates manually:';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A.';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B.';
            app.SaveandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.CreategraphicallyLabel = uilabel(app.UIFigure);
            app.CreategraphicallyLabel.Text = 'Create graphically:';
            app.maxtimeEditFieldLabel = uilabel(app.UIFigure);
            app.maxtimeEditFieldLabel.Text = 'max time';
            app.maxtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxtimeEditField.Value = 300;
            app.maxtempEditFieldLabel = uilabel(app.UIFigure);
            app.maxtempEditFieldLabel.Text = 'max temp';
            app.maxtempEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxtempEditField.Value = 35;
            app.pointsLabel = uilabel(app.UIFigure);
            app.pointsLabel.Text = 'points';
            app.pointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.pointsEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = profile_creator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end